classdef MultiLayerReflection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UserInputsPanel           matlab.ui.container.Panel
        NumberMediaDropDownLabel  matlab.ui.control.Label
        NumberMediaDropDown       matlab.ui.control.DropDown
        PlaySwitchLabel           matlab.ui.control.Label
        PlaySwitch                matlab.ui.control.Switch
        AngleInKnobLabel          matlab.ui.control.Label
        AngleInKnob               matlab.ui.control.DiscreteKnob
        SelectMediaPanel          matlab.ui.container.Panel
        Media1DropDownLabel       matlab.ui.control.Label
        Media1DropDown            matlab.ui.control.DropDown
        EditField                 matlab.ui.control.NumericEditField
        MRaylsLabel               matlab.ui.control.Label
        MRaylsLabel_2             matlab.ui.control.Label
        EditField_2               matlab.ui.control.NumericEditField
        Media1DropDown_2          matlab.ui.control.DropDown
        Media1DropDownLabel_2     matlab.ui.control.Label
        ImAxe                     matlab.ui.control.UIAxes
        OutputsPanel              matlab.ui.container.Panel
        O1GaugeLabel              matlab.ui.control.Label
        O1Gauge                   matlab.ui.control.NinetyDegreeGauge
        O2GaugeLabel              matlab.ui.control.Label
        O2Gauge                   matlab.ui.control.NinetyDegreeGauge
    end


    properties (Access = private)
        cMapLong 
        zMapLong
        cMapShear 
        zMapShear
        degIn = 0;
        degOut = 0;
        %degOutShear = 0;
        Rf = 0; %Reflection
        Tf = 0; %Transmitted in Longitudinal Direction (z)
        %Tf_s = 0;%Transmitted in Shear Horizontal Direction (x)
        
        % Color Map Use
            jetColor = [0         0    0.5625;...
                     0         0    0.6250; ...
                     0         0    0.6875;...
                     0         0    0.7500;...
                     0         0    0.8125;...
                     0         0    0.8750;...
                     0         0    0.9375;...
                     0         0    1.0000;...
                     0    0.0625    1.0000;...
                     0    0.1250    1.0000;...
                     0    0.1875    1.0000;...
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                0.5000         0         0];
    end

    methods (Access = private)
    
        function Draw_Line(app)
            % Split Axes into divisions based on number of desired media
            div = str2num(app.NumberMediaDropDown.Value);
            
            %No need to draw a line if only 1 media!
            if div == 1
                plot(app.ImAxe,1,1);
                axis(app.ImAxe,'off');
                axis(app.ImAxe,[0 1 0 1]);
                return
            end 
            
            %Start plot, define axis range
            plot(app.ImAxe,1,1);
            axis(app.ImAxe,'off');
            axis(app.ImAxe,[0 1 0 1]);
            
            %Draw Lines
            for i = 1:div-1
                line(app.ImAxe,[0 1], [i/div i/div],'LineWidth',2,'Color','k');
            end
            %Define color scheme used by MATLAB moving forward (note our jetColor property colors this!)
            colormap(app.ImAxe,'jet');
            colorbar(app.ImAxe);
        end
        
        function drawOne(app)
            
        end
        
        function Propegate(app)
            app.degIn = 45;
            angle = app.degIn;
            step = 0.01; %Size of line I draw after each time interval
            colorStart = app.jetColor(round(1*63)+1,:);
            x2 = 0;
            y2 = 0;
            
            while(y2<0.5)
                x1 = x2;
                y1 = y2;
                x2 = x2+step*cosd(angle);
                y2 = y2+step*sin(angle);
                line(app.ImAxe,[x1 x2], [y1 y2],'LineWidth',2,'Color',colorStart);
                pause(0.075)
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Create a default graph users may use           
            app.Draw_Line();
            %Load Excel File containing media info
        end

        % Value changed function: NumberMediaDropDown
        function NumberMediaDropDownValueChanged(app, event)
           %When the dropdown changes, draw lines for the media divides
            app.Draw_Line()  
        end

        % Value changed function: PlaySwitch
        function PlaySwitchValueChanged(app, event)
            if app.PlaySwitch.Value == 'On'
                app.Propegate();
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UserInputsPanel = uipanel(app.UIFigure);
            app.UserInputsPanel.Title = 'User Inputs';
            app.NumberMediaDropDownLabel = uilabel(app.UserInputsPanel);
            app.NumberMediaDropDownLabel.Text = 'Number Media';
            app.NumberMediaDropDown = uidropdown(app.UserInputsPanel);
            app.NumberMediaDropDown.Items = {'2', '1', '3', '4'};
            app.NumberMediaDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberMediaDropDownValueChanged, true);
            app.NumberMediaDropDown.Value = '2';
            app.PlaySwitchLabel = uilabel(app.UserInputsPanel);
            app.PlaySwitchLabel.Text = 'Play';
            app.PlaySwitch = uiswitch(app.UserInputsPanel, 'slider');
            app.PlaySwitch.ValueChangedFcn = createCallbackFcn(app, @PlaySwitchValueChanged, true);
            app.AngleInKnobLabel = uilabel(app.UserInputsPanel);
            app.AngleInKnobLabel.Text = 'Angle In';
            app.AngleInKnob = uiknob(app.UserInputsPanel, 'discrete');
            app.AngleInKnob.Items = {'0', '30', '60', '90'};
            app.AngleInKnob.Value = '0';
            app.SelectMediaPanel = uipanel(app.UIFigure);
            app.SelectMediaPanel.Title = 'Select Media';
            app.Media1DropDownLabel = uilabel(app.SelectMediaPanel);
            app.Media1DropDownLabel.Text = 'Media 1';
            app.Media1DropDown = uidropdown(app.SelectMediaPanel);
            app.EditField = uieditfield(app.SelectMediaPanel, 'numeric');
            app.MRaylsLabel = uilabel(app.SelectMediaPanel);
            app.MRaylsLabel.Text = 'MRayls';
            app.MRaylsLabel_2 = uilabel(app.SelectMediaPanel);
            app.MRaylsLabel_2.Text = 'MRayls';
            app.EditField_2 = uieditfield(app.SelectMediaPanel, 'numeric');
            app.Media1DropDown_2 = uidropdown(app.SelectMediaPanel);
            app.Media1DropDownLabel_2 = uilabel(app.SelectMediaPanel);
            app.Media1DropDownLabel_2.Text = 'Media 2';
            app.ImAxe = uiaxes(app.UIFigure);
            title(app.ImAxe, 'Title')
            xlabel(app.ImAxe, 'X')
            ylabel(app.ImAxe, 'Y')
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.O1GaugeLabel = uilabel(app.OutputsPanel);
            app.O1GaugeLabel.Text = 'O1';
            app.O1Gauge = uigauge(app.OutputsPanel, 'ninetydegree');
            app.O1Gauge.Limits = [0 90];
            app.O2GaugeLabel = uilabel(app.OutputsPanel);
            app.O2GaugeLabel.Text = 'O2';
            app.O2Gauge = uigauge(app.OutputsPanel, 'ninetydegree');
        end
    end

    methods (Access = public)

        % Construct app
        function app = MultiLayerReflection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end