classdef Fourier_Transform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TimeAxes                 matlab.ui.control.UIAxes
        FreqAxes                 matlab.ui.control.UIAxes
        UserInputsPanel          matlab.ui.container.Panel
        InputSignalListBoxLabel  matlab.ui.control.Label
        InputSignalListBox       matlab.ui.control.ListBox
        ToneBurstLabel           matlab.ui.control.Label
        FreqMHzLabel             matlab.ui.control.Label
        ToneBurstFreqSlider      matlab.ui.control.Slider
        Label                    matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        GaussianLabel            matlab.ui.control.Label
        CenterTimeuSLabel_3      matlab.ui.control.Label
        GaussianFreqSlider       matlab.ui.control.Slider
        EditField_2Label         matlab.ui.control.Label
        EditField_2              matlab.ui.control.NumericEditField
        SuperGaussianLabel       matlab.ui.control.Label
        CenterTimeuSLabel_2      matlab.ui.control.Label
        SuperGaussianFreqSlider  matlab.ui.control.Slider
        EditField_3Label         matlab.ui.control.Label
        EditField_3              matlab.ui.control.NumericEditField
        OrderKnobLabel           matlab.ui.control.Label
        SuperGaussianKnob        matlab.ui.control.DiscreteKnob
        DeltaLabel               matlab.ui.control.Label
        CenterTimeuSLabel        matlab.ui.control.Label
        DeltaFreqSlider          matlab.ui.control.Slider
        EditField_4Label         matlab.ui.control.Label
        EditField_4              matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Signal = 'T'; %Signal Type Selected, default tone burst
    end

    methods (Access = private)
    
        function plot_app(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Ft = @(x) fftshift(fft(ifftshift(x))); %fft
            %Note you do not want to over sample time domain as this decreases resolution in frequency domain
            %delta_t*delta_f = 2*pi/N, where N is the number of points used
            T = linspace(0,5,100); %Time domain sampling, sampled at Nyquest
            S = @(f) sin(f*T); %Tone Burst
            G = @(f) 1*exp(-(T-f).^2./(2));
            SG = @(f,o) (1*exp(-(T-f).^2./(2))).^o;

            f = app.ToneBurstFreqSlider.Value;
            F = linspace(-2,2,100); %Freq domain sampling
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(T,S(f),'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(F,abs(Ft(S(f))), 'Parent', app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
        end

        % Value changed function: InputSignalListBox
        function InputSignalListBoxValueChanged(app, event)
            Ft = @(x) fftshift(fft(ifftshift(x))); %fft
            value = app.InputSignalListBox.Value;
            if value(1) == 'T'
                T = linspace(0,5,100); %Time domain sampling, sampled at Nyquest
                S = @(f) sin(f*T); %Tone Burst
                f = app.ToneBurstFreqSlider.Value;
                F = linspace(-2,2,100); %Freq domain sampling
            
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(T,S(f),'Parent', app.TimeAxes)
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(F,abs(Ft(S(f))), 'Parent', app.FreqAxes)
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Amplitude')
            
            elseif value(1) == 'G'
                T = linspace(0,5,10); %Time domain sampling, sampled at Nyquest
                G = @(f) 1*exp(-(T-f).^2./(2));
                f = app.GaussianFreqSlider.Value;
                F = linspace(-2,2,10); %Freq domain sampling
            
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(T,G(f),'Parent', app.TimeAxes)
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(F,abs(Ft(G(f))), 'Parent', app.FreqAxes)
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Amplitude')
                
            elseif value(1) == 'S'
                T = linspace(0,5,75); %Time domain sampling, sampled at Nyquest
                SG = @(f,o) (1*exp(-(T-f).^o./(2)));
                f = app.SuperGaussianFreqSlider.Value;
                o = app.SuperGaussianKnob.Value;
                F = linspace(-2,2,75); %Freq domain sampling
            
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(T,SG(f,o)./max(SG(f,o)),'Parent', app.TimeAxes)
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(F,abs(Ft(SG(f,o))), 'Parent', app.FreqAxes)
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Amplitude')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TimeAxes = uiaxes(app.UIFigure);
            title(app.TimeAxes, 'Time Domain')
            xlabel(app.TimeAxes, 'Time')
            ylabel(app.TimeAxes, 'Signal')
            app.TimeAxes.XLim = [0 1];
            app.TimeAxes.YLim = [0 1];
            app.TimeAxes.ZLim = [0 1];
            app.TimeAxes.CLim = [0 1];
            app.TimeAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TimeAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.TimeAxes.ZTick = [0 0.5 1];
            app.FreqAxes = uiaxes(app.UIFigure);
            title(app.FreqAxes, 'Fourier Transform')
            xlabel(app.FreqAxes, 'Frequency')
            ylabel(app.FreqAxes, 'Signal')
            app.FreqAxes.XLim = [0 1];
            app.FreqAxes.YLim = [0 1];
            app.FreqAxes.ZLim = [0 1];
            app.FreqAxes.CLim = [0 1];
            app.FreqAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.FreqAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.FreqAxes.ZTick = [0 0.5 1];
            app.UserInputsPanel = uipanel(app.UIFigure);
            app.UserInputsPanel.Title = 'User Inputs';
            app.InputSignalListBoxLabel = uilabel(app.UserInputsPanel);
            app.InputSignalListBoxLabel.Text = {'Input'; 'Signal'};
            app.InputSignalListBox = uilistbox(app.UserInputsPanel);
            app.InputSignalListBox.Items = {'Tone Burst', 'Gaussian', 'Super-Gaussian', 'Delta'};
            app.InputSignalListBox.ValueChangedFcn = createCallbackFcn(app, @InputSignalListBoxValueChanged, true);
            app.InputSignalListBox.Value = 'Tone Burst';
            app.ToneBurstLabel = uilabel(app.UserInputsPanel);
            app.ToneBurstLabel.Text = 'Tone Burst';
            app.FreqMHzLabel = uilabel(app.UserInputsPanel);
            app.FreqMHzLabel.Text = {'Freq.'; '(MHz)'};
            app.ToneBurstFreqSlider = uislider(app.UserInputsPanel);
            app.ToneBurstFreqSlider.Limits = [1 10];
            app.ToneBurstFreqSlider.Value = 5;
            app.Label = uilabel(app.UserInputsPanel);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.EditField.Limits = [1 10];
            app.EditField.Value = 1;
            app.GaussianLabel = uilabel(app.UserInputsPanel);
            app.GaussianLabel.Text = 'Gaussian';
            app.CenterTimeuSLabel_3 = uilabel(app.UserInputsPanel);
            app.CenterTimeuSLabel_3.Text = {'Center'; 'Time'; '(uS)'};
            app.GaussianFreqSlider = uislider(app.UserInputsPanel);
            app.GaussianFreqSlider.Limits = [1 5];
            app.GaussianFreqSlider.Value = 1;
            app.EditField_2Label = uilabel(app.UserInputsPanel);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.UserInputsPanel, 'numeric');
            app.EditField_2.Limits = [1 5];
            app.EditField_2.Value = 1;
            app.SuperGaussianLabel = uilabel(app.UserInputsPanel);
            app.SuperGaussianLabel.Text = 'Super-Gaussian';
            app.CenterTimeuSLabel_2 = uilabel(app.UserInputsPanel);
            app.CenterTimeuSLabel_2.Text = {'Center'; 'Time'; '(uS)'};
            app.SuperGaussianFreqSlider = uislider(app.UserInputsPanel);
            app.SuperGaussianFreqSlider.Limits = [1 5];
            app.SuperGaussianFreqSlider.Value = 1;
            app.EditField_3Label = uilabel(app.UserInputsPanel);
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.UserInputsPanel, 'numeric');
            app.EditField_3.Limits = [1 5];
            app.EditField_3.Value = 1;
            app.OrderKnobLabel = uilabel(app.UserInputsPanel);
            app.OrderKnobLabel.Text = 'Order';
            app.SuperGaussianKnob = uiknob(app.UserInputsPanel, 'discrete');
            app.SuperGaussianKnob.Items = {'1', '2', '3', '4', '5'};
            app.SuperGaussianKnob.Value = '2';
            app.DeltaLabel = uilabel(app.UserInputsPanel);
            app.DeltaLabel.Text = 'Delta';
            app.CenterTimeuSLabel = uilabel(app.UserInputsPanel);
            app.CenterTimeuSLabel.Text = {'Center'; 'Time'; '(uS)'};
            app.DeltaFreqSlider = uislider(app.UserInputsPanel);
            app.DeltaFreqSlider.Limits = [1 5];
            app.DeltaFreqSlider.Value = 1;
            app.EditField_4Label = uilabel(app.UserInputsPanel);
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.UserInputsPanel, 'numeric');
            app.EditField_4.Limits = [1 5];
            app.EditField_4.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fourier_Transform

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end