classdef Fourier_Transform_3_DB_Scaling_and_Labels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TimeAxes                   matlab.ui.control.UIAxes
        FreqAxes                   matlab.ui.control.UIAxes
        UserInputsPanel            matlab.ui.container.Panel
        InputSignalListBoxLabel    matlab.ui.control.Label
        InputSignalListBox         matlab.ui.control.ListBox
        ToneBurstLabel             matlab.ui.control.Label
        FreqMHzLabel               matlab.ui.control.Label
        ToneBurstFreqSlider        matlab.ui.control.Slider
        Label                      matlab.ui.control.Label
        ToneBurstFreqEditField     matlab.ui.control.NumericEditField
        GaussianLabel              matlab.ui.control.Label
        VarLabel                   matlab.ui.control.Label
        GaussianVarSlider          matlab.ui.control.Slider
        EditField_2Label           matlab.ui.control.Label
        GaussianVarEditField       matlab.ui.control.NumericEditField
        SuperGaussianLabel         matlab.ui.control.Label
        VarLabel_2                 matlab.ui.control.Label
        SuperGaussianVarSlider     matlab.ui.control.Slider
        EditField_3Label           matlab.ui.control.Label
        SuperGaussianVarEditField  matlab.ui.control.NumericEditField
        OrderKnobLabel             matlab.ui.control.Label
        SuperGaussianKnob          matlab.ui.control.DiscreteKnob
        DeltaLabel                 matlab.ui.control.Label
        CenterTimeuSLabel          matlab.ui.control.Label
        DeltaCenterSlider          matlab.ui.control.Slider
        EditField_4Label           matlab.ui.control.Label
        DeltaCenterEditField       matlab.ui.control.NumericEditField
        ViewCodeButton             matlab.ui.control.Button
    end

    properties (Access = private)
        Signal = 'T'; %Signal Type Selected, default tone burst
        T = linspace(0,5,100); %Time domain sampling, sampled at Nyquest
        F = linspace(-pi/0.101,pi/0.101,100); %Freq domain sampling       
    end

    methods (Access = private)
        %Finds the index of any pixel containing a specified db value in a signal (converted to db)
        function pixels = findDB(app,signal,db)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Ft = @(x) fftshift(fft(x,200));
            %Note you do not want to over sample time domain as this decreases resolution in frequency domain
            %delta_t*delta_f = 2*pi/N, where N is the number of points used
            S = @(f) sin(f*app.T); %Tone Burst

            f = app.ToneBurstFreqSlider.Value;
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,S(f),'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            df = abs(Ft(S(f)));
            plot(app.F,df(51:150), 'Parent', app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
        end

        % Value changed function: InputSignalListBox
        function InputSignalListBoxValueChanged(app, event)
            Ft = @(x) fftshift(fft(x,200)); %fft. Note I pad the FFT by twice the length b/c fft is a circular convolution. This prevents issues by wrapping
            value = app.InputSignalListBox.Value;
            if value(1) == 'T'
                S = @(f) sin(f*app.T); %Tone Burst
                f = app.ToneBurstFreqSlider.Value;
                
                %Generate signal, fft and scale to db
                signal = mag2db(S(f));
                transform = mag2db(abs(Ft(S(f))));
                
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(app.T,signal,'Parent', app.TimeAxes)
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'DB')
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(app.F,transform(51:150), 'Parent', app.FreqAxes) %transform is indexed b/c padding the original signal in the fft produces a longer result
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'DB')
            
            elseif value(1) == 'G'
                G = @(v) 1*exp(-(app.T-2.5).^2./(v));
                v = app.GaussianVarSlider.Value;
               
                %Generate signal, fft and scale to db
                signal = mag2db(G(v));
                transform = mag2db(abs(Ft(G(v))));
                
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(app.T,signal,'Parent', app.TimeAxes)
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'DB')
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(app.F(31:72),transform(81:122), 'Parent', app.FreqAxes)
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Amplitude')
                
            elseif value(1) == 'S'
                SG = @(v,o) ((1*exp(-(app.T-2.5).^(2*o))./(v))); %Standard gaussian to the o-th power
                v = app.SuperGaussianVarSlider.Value;
                o = str2num(app.SuperGaussianKnob.Value);
                
                %Generate signal, fft and scale to db
                signal = mag2db(SG(v,o));
                transform = mag2db(abs(Ft(SG(v,o))));
                
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(app.T,signal,'Parent', app.TimeAxes)
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'DB')
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(app.F(31:72),transform(81:122), 'Parent', app.FreqAxes)
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'DB')
                
           elseif value(1) == 'D'
                t = app.DeltaCenterSlider.Value; %Get center
                t = round(t/5*99)+1; %Round to closest spot in graph
                delta = zeros(size(app.T))-60; %Define zeros
                delta(t) = 0; %Assign single point to one
                
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(app.T,delta,'Parent', app.TimeAxes)
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(app.F,zeros(1,100),'Parent',app.FreqAxes)
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Amplitude')
            end
        end

        % Value changed function: DeltaCenterSlider
        function DeltaCenterSliderValueChanged(app, event)
            value = app.DeltaCenterSlider.Value;
            t = round(value/5*99)+1; %Round to closest spot in graph
            delta = zeros(size(app.T))-60; %Define zeros
            delta(t) = 0; %Assign single point to one
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,delta,'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,zeros(1,100),'Parent',app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            %Update Edit Field
            app.DeltaCenterEditField.Value = value;
            app.InputSignalListBox.Value = 'Delta';
        end

        % Value changed function: DeltaCenterEditField
        function DeltaCenterEditFieldValueChanged(app, event)
            value = app.DeltaCenterEditField.Value;
            t = round(value/5*99)+1; %Round to closest spot in graph
            delta = zeros(size(app.T))-60; %Define zeros
            delta(t) = 0; %Assign single point to one
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,delta,'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,zeros(1,100),'Parent',app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            %Update Slider
            app.DeltaCenterSlider.Value = value;
            app.InputSignalListBox.Value = 'Delta';
        end

        % Value changed function: ToneBurstFreqSlider
        function ToneBurstFreqSliderValueChanged(app, event)
            value = app.ToneBurstFreqSlider.Value;
            Ft = @(x) fftshift(fft(x,200)); 
            S = @(f) sin(f*app.T); %Tone Burst
            f = value;
            
            %Generate signal, fft and scale to db
            signal = mag2db(S(f));
            transform = mag2db(abs(Ft(S(f))));
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,signal,'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'DB')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,transform(51:150), 'Parent', app.FreqAxes) %transform is indexed b/c padding the original signal in the fft produces a longer result
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'DB')
            
            %Update Edit Field
            app.ToneBurstFreqEditField.Value = value;
            app.InputSignalListBox.Value = 'Tone Burst';
        end

        % Value changed function: ToneBurstFreqEditField
        function ToneBurstFreqEditFieldValueChanged(app, event)
            value = app.ToneBurstFreqSlider.Value;
            Ft = @(x) fftshift(fft(x,200)); 
            S = @(f) sin(f*app.T); %Tone Burst
            f = value;
            
            %Generate signal, fft and scale to db
            signal = mag2db(S(f));
            transform = mag2db(abs(Ft(S(f))));
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,signal,'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'DB')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,transform(51:150), 'Parent', app.FreqAxes) %transform is indexed b/c padding the original signal in the fft produces a longer result
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'DB')
            
            %Update Edit Field
            app.ToneBurstFreqSlider.Value = value;
            app.InputSignalListBox.Value = 'Tone Burst';
        end

        % Value changed function: GaussianVarSlider
        function GaussianVarSliderValueChanged(app, event)
            value = app.GaussianVarSlider.Value;
            Ft = @(x) fftshift(fft(x,200));
            G = @(v) 1*exp(-(app.T-2.5).^2./(v));
            v = value;

            %Generate signal, fft and scale to db
            signal = mag2db(G(v));
            transform = mag2db(abs(Ft(G(v))));
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,signal,'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'DB')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F(31:72),transform(81:122), 'Parent', app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
        
            %Update Edit Field
            app.GaussianVarEditField.Value = value;
            app.InputSignalListBox.Value = 'Gaussian';
        end

        % Value changed function: GaussianVarEditField
        function GaussianVarEditFieldValueChanged(app, event)
            value = app.GaussianVarEditField.Value;
            G = @(v) 1*exp(-(app.T-2.5).^2./(v));
            Ft = @(x) fftshift(fft(x,200));
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,G(value),'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            df = abs(Ft(G(value)));
            plot(app.F(31:72),df(81:122), 'Parent', app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            %Update Edit Field
            app.GaussianVarSlider.Value = value;
            app.InputSignalListBox.Value = 'Gaussian';
        end

        % Value changed function: SuperGaussianVarSlider
        function SuperGaussianVarSliderValueChanged(app, event)
            value = app.SuperGaussianVarSlider.Value;
            SG = @(v,o) ((1*exp(-(app.T-2.5).^(2*o))./(v))); %Standard gaussian to the o-th power
            Ft = @(x) fftshift(fft(x,200));
            v = value;
            o = str2num(app.SuperGaussianKnob.Value);

            %Generate signal, fft and scale to db
            signal = mag2db(SG(v,o));
            transform = mag2db(abs(Ft(SG(v,o))));
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,signal,'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'DB')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F(31:72),transform(81:122), 'Parent', app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'DB')
            
            %Update Edit Field
            app.SuperGaussianVarEditField.Value = value;
            app.InputSignalListBox.Value = 'Super-Gaussian';
        end

        % Value changed function: SuperGaussianVarEditField
        function SuperGaussianVarEditFieldValueChanged(app, event)
            value = app.SuperGaussianVarEditField.Value;
            SG = @(v,o) ((1*exp(-(app.T-2.5).^(2*o))./(v))); %Standard gaussian to the o-th power
            Ft = @(x) fftshift(fft(x,200));
            v = value;
            o = str2num(app.SuperGaussianKnob.Value);

            %Generate signal, fft and scale to db
            signal = mag2db(SG(v,o));
            transform = mag2db(abs(Ft(SG(v,o))));
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,signal,'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'DB')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F(31:72),transform(81:122), 'Parent', app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'DB')
            
            %Update Edit Field
            app.SuperGaussianVarEditField.Value = value;
            app.InputSignalListBox.Value = 'Super-Gaussian';
        end

        % Value changed function: SuperGaussianKnob
        function SuperGaussianKnobValueChanged(app, event)
            value = str2num(app.SuperGaussianKnob.Value);
            SG = @(v,o) ((1*exp(-(app.T-2.5).^(2*o))./(v))); %Standard gaussian to the o-th power
            Ft = @(x) fftshift(fft(x,200));
            v = app.SuperGaussianVarSlider.Value;
            o = value;

            %Generate signal, fft and scale to db
            signal = mag2db(SG(v,o));
            transform = mag2db(abs(Ft(SG(v,o))));
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,signal,'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'DB')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F(31:72),transform(81:122), 'Parent', app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'DB')
            
            app.InputSignalListBox.Value = 'Super-Gaussian';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TimeAxes = uiaxes(app.UIFigure);
            title(app.TimeAxes, 'Time Domain')
            xlabel(app.TimeAxes, 'Time')
            ylabel(app.TimeAxes, 'Signal')
            app.TimeAxes.XLim = [0 1];
            app.TimeAxes.YLim = [0 1];
            app.TimeAxes.ZLim = [0 1];
            app.TimeAxes.CLim = [0 1];
            app.TimeAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TimeAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.TimeAxes.ZTick = [0 0.5 1];
            app.FreqAxes = uiaxes(app.UIFigure);
            title(app.FreqAxes, 'Fourier Transform')
            xlabel(app.FreqAxes, 'Frequency')
            ylabel(app.FreqAxes, 'Signal')
            app.FreqAxes.XLim = [0 1];
            app.FreqAxes.YLim = [0 1];
            app.FreqAxes.ZLim = [0 1];
            app.FreqAxes.CLim = [0 1];
            app.FreqAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.FreqAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.FreqAxes.ZTick = [0 0.5 1];
            app.UserInputsPanel = uipanel(app.UIFigure);
            app.UserInputsPanel.Title = 'User Inputs';
            app.InputSignalListBoxLabel = uilabel(app.UserInputsPanel);
            app.InputSignalListBoxLabel.Text = {'Input'; 'Signal'};
            app.InputSignalListBox = uilistbox(app.UserInputsPanel);
            app.InputSignalListBox.Items = {'Tone Burst', 'Gaussian', 'Super-Gaussian', 'Delta'};
            app.InputSignalListBox.ValueChangedFcn = createCallbackFcn(app, @InputSignalListBoxValueChanged, true);
            app.InputSignalListBox.Value = 'Tone Burst';
            app.ToneBurstLabel = uilabel(app.UserInputsPanel);
            app.ToneBurstLabel.Text = 'Tone Burst';
            app.FreqMHzLabel = uilabel(app.UserInputsPanel);
            app.FreqMHzLabel.Text = {'Freq.'; '(MHz)'};
            app.ToneBurstFreqSlider = uislider(app.UserInputsPanel);
            app.ToneBurstFreqSlider.Limits = [1 10];
            app.ToneBurstFreqSlider.ValueChangedFcn = createCallbackFcn(app, @ToneBurstFreqSliderValueChanged, true);
            app.ToneBurstFreqSlider.Value = 5;
            app.Label = uilabel(app.UserInputsPanel);
            app.Label.Text = '';
            app.ToneBurstFreqEditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.ToneBurstFreqEditField.Limits = [1 10];
            app.ToneBurstFreqEditField.ValueChangedFcn = createCallbackFcn(app, @ToneBurstFreqEditFieldValueChanged, true);
            app.ToneBurstFreqEditField.Value = 5;
            app.GaussianLabel = uilabel(app.UserInputsPanel);
            app.GaussianLabel.Text = 'Gaussian';
            app.VarLabel = uilabel(app.UserInputsPanel);
            app.VarLabel.Text = 'Var';
            app.GaussianVarSlider = uislider(app.UserInputsPanel);
            app.GaussianVarSlider.Limits = [1 5];
            app.GaussianVarSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianVarSliderValueChanged, true);
            app.GaussianVarSlider.Value = 1;
            app.EditField_2Label = uilabel(app.UserInputsPanel);
            app.EditField_2Label.Text = '';
            app.GaussianVarEditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.GaussianVarEditField.Limits = [1 5];
            app.GaussianVarEditField.ValueChangedFcn = createCallbackFcn(app, @GaussianVarEditFieldValueChanged, true);
            app.GaussianVarEditField.Value = 1;
            app.SuperGaussianLabel = uilabel(app.UserInputsPanel);
            app.SuperGaussianLabel.Text = 'Super-Gaussian';
            app.VarLabel_2 = uilabel(app.UserInputsPanel);
            app.VarLabel_2.Text = 'Var';
            app.SuperGaussianVarSlider = uislider(app.UserInputsPanel);
            app.SuperGaussianVarSlider.Limits = [1 5];
            app.SuperGaussianVarSlider.ValueChangedFcn = createCallbackFcn(app, @SuperGaussianVarSliderValueChanged, true);
            app.SuperGaussianVarSlider.Value = 1;
            app.EditField_3Label = uilabel(app.UserInputsPanel);
            app.EditField_3Label.Text = '';
            app.SuperGaussianVarEditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.SuperGaussianVarEditField.Limits = [1 5];
            app.SuperGaussianVarEditField.ValueChangedFcn = createCallbackFcn(app, @SuperGaussianVarEditFieldValueChanged, true);
            app.SuperGaussianVarEditField.Value = 1;
            app.OrderKnobLabel = uilabel(app.UserInputsPanel);
            app.OrderKnobLabel.Text = 'Order';
            app.SuperGaussianKnob = uiknob(app.UserInputsPanel, 'discrete');
            app.SuperGaussianKnob.Items = {'1', '2', '3', '4', '5'};
            app.SuperGaussianKnob.ValueChangedFcn = createCallbackFcn(app, @SuperGaussianKnobValueChanged, true);
            app.SuperGaussianKnob.Value = '1';
            app.DeltaLabel = uilabel(app.UserInputsPanel);
            app.DeltaLabel.Text = 'Delta';
            app.CenterTimeuSLabel = uilabel(app.UserInputsPanel);
            app.CenterTimeuSLabel.Text = {'Center'; 'Time'; '(uS)'};
            app.DeltaCenterSlider = uislider(app.UserInputsPanel);
            app.DeltaCenterSlider.Limits = [0 5];
            app.DeltaCenterSlider.ValueChangedFcn = createCallbackFcn(app, @DeltaCenterSliderValueChanged, true);
            app.DeltaCenterSlider.Value = 2.5;
            app.EditField_4Label = uilabel(app.UserInputsPanel);
            app.EditField_4Label.Text = '';
            app.DeltaCenterEditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.DeltaCenterEditField.Limits = [0 5];
            app.DeltaCenterEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaCenterEditFieldValueChanged, true);
            app.DeltaCenterEditField.Value = 2.5;
            app.ViewCodeButton = uibutton(app.UserInputsPanel, 'push');
            app.ViewCodeButton.Text = 'View Code';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fourier_Transform_3_DB_Scaling_and_Labels

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end